classdef project_BoidsSimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AboutMenu                      matlab.ui.container.Menu
        AuthorMenu                     matlab.ui.container.Menu
        ApplicationMenu                matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        Switch                         matlab.ui.control.ToggleSwitch
        BorderAvoidanceLamp            matlab.ui.control.Lamp
        BorderAvoidanceSlider          matlab.ui.control.Slider
        BorderAvoidanceSliderLabel     matlab.ui.control.Label
        PredatorsVisionDistanceSlider  matlab.ui.control.Slider
        PredatorsVisionDistanceLabel   matlab.ui.control.Label
        BoidsNumberSlider              matlab.ui.control.Slider
        BoidsNumberSliderLabel         matlab.ui.control.Label
        PredatorsNumberSlider          matlab.ui.control.Slider
        PredatorsNumberSliderLabel     matlab.ui.control.Label
        PredatorAvoidanceLamp          matlab.ui.control.Lamp
        AlignmentLamp                  matlab.ui.control.Lamp
        CohensionLamp                  matlab.ui.control.Lamp
        SeparationLamp                 matlab.ui.control.Lamp
        PredatorAvoidanceSlider        matlab.ui.control.Slider
        PredatorAvoidanceSliderLabel   matlab.ui.control.Label
        AlignmentSlider                matlab.ui.control.Slider
        AlignmentSliderLabel           matlab.ui.control.Label
        SeparationSlider               matlab.ui.control.Slider
        SeparationSliderLabel          matlab.ui.control.Label
        CohensionSlider                matlab.ui.control.Slider
        CohensionSliderLabel           matlab.ui.control.Label
        Plane                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mouseRelease
	    minWidth = 800;
	    minHeight = 640;
	    lastPos

        Timer
        
        boids
        planeBoids
        boidsNumber
        BOIDS_MAX_NUMBER

        predators
        planePredators
        predatorsNumber
        PREDATORS_MAX_NUMBER
        predatorsHalfVision
        displayPredatorVision = 'On';

        frameEnded
    end

    properties (Constant)
        BOIDS_MAX_SPEED = 2;
        PREDATORS_MAX_SPEED = 1.5;
        PLANE_WIDTH = 300;
        PLANE_HEIGHT = 300;
        MAX_BOIDS_NEIGHBORS = 20;
        MAX_PREDATORS_NEIGHBORS = 20;
    end
    
    methods (Access = private)

        function updatePlot(app)
            if app.frameEnded
                app.frameEnded = false;
                
                %tic
                for i=1:app.boidsNumber
                    app.boids(i).update(app.boids, app.predators, app.predatorsNumber);
                end

                for i=1:app.predatorsNumber
                    app.predators(i).update(app.boids, app.boidsNumber, app.predators, app.predatorsNumber);
                end
                %toc

                plot(app.Plane, 0, 0);
                app.drawBoids();
                app.drawPredators();

                drawnow; % Force immediate update
                app.frameEnded = true;
            end
        end
        
        function drawBoids(app)
             for i=1:app.boidsNumber
                % Reset previous boid, inorder not to draw its previous position
                delete(app.planeBoids(i)); 
                % Calculate boid's position on plane and its rotation angle
                theta = rad2deg(angle(app.boids(i).velocity(1)+1i*app.boids(i).velocity(2)));
                x = [app.boids(i).position(1)-2 app.boids(i).position(1)+2 app.boids(i).position(1)+2 app.boids(i).position(1)-2];
                y = [app.boids(i).position(2)-1 app.boids(i).position(2)-1 app.boids(i).position(2)+1 app.boids(i).position(2)+1];
                % Save boids position and shape
                app.planeBoids(i) = patch(app.Plane, x,y,0,'FaceColor', [app.boids(i).color_r, app.boids(i).color_g, app.boids(i).color_b], 'EdgeColor', 'k');
                % Draw rotated boid
                rotate(app.planeBoids(i), [0 0 1], theta, [app.boids(i).position(1) app.boids(i).position(2) 0]);
            end
        end
        
        function drawPredators(app)
            for i=1:app.predatorsNumber
                delete(app.planePredators(i)); 
                theta = rad2deg(angle(app.predators(i).velocity(1)+1i*app.predators(i).velocity(2)));
                x = [app.predators(i).position(1)-1 app.predators(i).position(1)+1 app.predators(i).position(1)-1];
                y = [app.predators(i).position(2)-1 app.predators(i).position(2)   app.predators(i).position(2)+1];
                app.planePredators(i) = patch(app.Plane, x,y,0,'FaceColor', 'red', 'EdgeColor', 'k');
                rotate(app.planePredators(i), [0 0 1], theta, [app.predators(i).position(1) app.predators(i).position(2) 0]);
                % Display predator's vision, if enabled
                if app.displayPredatorVision == "On"
                    rectangle(app.Plane, 'Position', [app.predators(i).position(1)-app.predatorsHalfVision, ...
                                                      app.predators(i).position(2)-app.predatorsHalfVision, ...
                                                      app.PredatorsVisionDistanceSlider.Value app.PredatorsVisionDistanceSlider.Value], ...
                                        'Curvature', [1 1]);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Limit minimal window width and height
            %{ 
            import java.awt.*;
	        import java.awt.event.*;
	        rob = Robot;
	        app.mouseRelease = @() rob.mouseRelease(InputEvent.BUTTON1_DOWN_MASK);
	        app.lastPos = app.UIFigure.Position;
            %}

            % Initialize data
            app.Plane.XLim = [0, app.PLANE_WIDTH];
            app.Plane.YLim = [0, app.PLANE_HEIGHT];

            app.BOIDS_MAX_NUMBER = app.BoidsNumberSlider.Limits(2);
            app.PREDATORS_MAX_NUMBER = app.PredatorsNumberSlider.Limits(2);
            
            app.boidsNumber = app.BoidsNumberSlider.Value;
            app.predatorsNumber = app.PredatorsNumberSlider.Value;

            % Prepare boids
            Boid.alignmentMultiplier(app.AlignmentSlider.Value);
            Boid.borderAvoidanceMultiplier(app.BorderAvoidanceSlider.Value);
            Boid.cohensionMultiplier(app.CohensionSlider.Value);
            Boid.predatorAvoidanceMultiplier(app.PredatorAvoidanceSlider.Value);
            Boid.separationMultiplier(app.SeparationSlider.Value);

            boids(app.BOIDS_MAX_NUMBER) = Boid;
            for i=1:app.BOIDS_MAX_NUMBER
                boids(i).color_r = rand;
                boids(i).color_g = rand;
                boids(i).color_b = rand;
                boids(i).velocity = [rand * (app.BOIDS_MAX_SPEED * 2) - (app.BOIDS_MAX_SPEED * 0.5), rand * (app.BOIDS_MAX_SPEED * 2) - (app.BOIDS_MAX_SPEED * 0.5)];
                boids(i).position = [(rand * (app.PLANE_HEIGHT - 100)) + 50, (rand * (app.PLANE_WIDTH - 100)) + 50];
                boids(i).preallocateArrays(app.MAX_BOIDS_NEIGHBORS);
            end
            Boid.width(app.PLANE_WIDTH);
            Boid.height(app.PLANE_HEIGHT);
            Boid.maxSpeed(app.BOIDS_MAX_SPEED);
            Boid.borderStep(1);
            app.boids = boids;
            
            % Prepare predators
            predators(app.PREDATORS_MAX_NUMBER) = Predator;
            for i=1:app.PREDATORS_MAX_NUMBER
                predators(i).velocity = [rand * (app.PREDATORS_MAX_SPEED * 2) - (app.PREDATORS_MAX_SPEED * 0.5), rand * (app.PREDATORS_MAX_SPEED * 2) - (app.PREDATORS_MAX_SPEED * 0.5)];
                predators(i).position = [(rand * (app.PLANE_HEIGHT - 100)) + 50, (rand * (app.PLANE_WIDTH - 100)) + 50];
                predators(i).preallocateArrays(app.MAX_PREDATORS_NEIGHBORS);
            end
            Predator.width(app.PLANE_WIDTH);
            Predator.height(app.PLANE_HEIGHT);
            Predator.visionDistance(app.PredatorsVisionDistanceSlider.Value);
            app.predatorsHalfVision = app.PredatorsVisionDistanceSlider.Value * 0.5;
            Predator.maxSpeedPredator(app.PREDATORS_MAX_SPEED);
            Predator.borderStep(1);
            app.predators = predators;

            % Prepare plane
            plot(app.Plane, 0,0);
            for i=1:app.BOIDS_MAX_NUMBER
                x = [boids(i).position(1)-2.5 boids(i).position(1)+2.5 boids(i).position(1)-2.5 boids(i).position(1)-2.5];
                y = [boids(i).position(2)-1.5 boids(i).position(2) boids(i).position(2)+1.5 boids(i).position(2)+1.5];
                planeBoids(i) = patch(app.Plane, x, y, 'k');
            end
            app.planeBoids = planeBoids;
            for i=1:app.PREDATORS_MAX_NUMBER
                x = [predators(i).position(1)-2.5 predators(i).position(1)+2.5 predators(i).position(1)-2.5 predators(i).position(1)-2.5];
                y = [predators(i).position(2)-1.5 predators(i).position(2) predators(i).position(2)+1.5 predators(i).position(2)+1.5];
                planePredators(i) = patch(app.Plane, x, y, 'k');    
            end
            app.planePredators = planePredators;

            % limit to 30 fps
            app.frameEnded = true;
            app.Timer = timer('ExecutionMode', 'fixedRate', 'Period', 0.033, 'TimerFcn', @(~, ~)app.updatePlot());
            start(app.Timer);
        end

        % Value changed function: SeparationSlider
        function OnSeparationValueChanged(app, event)
            value = app.SeparationSlider.Value;
            Boid.separationMultiplier(value);
            if value == 0
                app.SeparationLamp.Enable = 'off';
                app.SeparationLamp.Color = 'black';
            else
                app.SeparationLamp.Enable = 'on';
                app.SeparationLamp.Color = 'green';
            end
        end

        % Value changed function: CohensionSlider
        function OnCohensionValueChanged(app, event)
            value = app.CohensionSlider.Value;
            Boid.cohensionMultiplier(value);
            if value == 0
                app.CohensionLamp.Enable = 'off';
                app.CohensionLamp.Color = 'black';
            else
                app.CohensionLamp.Enable = 'on';
                app.CohensionLamp.Color = 'green';
            end
        end

        % Value changed function: AlignmentSlider
        function OnAlignmentValueChanged(app, event)
            value = app.AlignmentSlider.Value;
            Boid.alignmentMultiplier(value);
            if value == 0
                app.AlignmentLamp.Enable = 'off';
                app.AlignmentLamp.Color = 'black';
            else
                app.AlignmentLamp.Enable = 'on';
                app.AlignmentLamp.Color = 'green';
            end
        end

        % Value changed function: PredatorAvoidanceSlider
        function OnPredatorAvoidanceValueChanged(app, event)
            value = app.PredatorAvoidanceSlider.Value;
            Boid.predatorAvoidanceMultiplier(value);
            if value == 0
                app.PredatorAvoidanceLamp.Enable = 'off';
                app.PredatorAvoidanceLamp.Color = 'black';
            else
                app.PredatorAvoidanceLamp.Enable = 'on';
                app.PredatorAvoidanceLamp.Color = 'green';
            end
        end

        % Value changed function: BorderAvoidanceSlider
        function OnBorderAvoidanceChanged(app, event)
            value = app.BorderAvoidanceSlider.Value;
            Boid.borderAvoidanceMultiplier(value);
            if value == 0
                app.BorderAvoidanceLamp.Enable = 'off';
                app.BorderAvoidanceLamp.Color = 'black';
            else
                app.BorderAvoidanceLamp.Enable = 'on';
                app.BorderAvoidanceLamp.Color = 'green';
            end
        end

        % Value changed function: BoidsNumberSlider
        function OnBoidsNumberChanged(app, event)
            app.boidsNumber = int32(app.BoidsNumberSlider.Value);
        end

        % Value changed function: PredatorsNumberSlider
        function OnPredatorsNumberChanged(app, event)
            app.predatorsNumber = int32(app.PredatorsNumberSlider.Value);
        end

        % Value changed function: PredatorsVisionDistanceSlider
        function OnPredatorsVisionChanged(app, event)
            Predator.visionDistance(app.PredatorsVisionDistanceSlider.Value);
            app.predatorsHalfVision = (app.PredatorsVisionDistanceSlider.Value) * 0.5;
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            %{
            position = app.UIFigure.Position;
	        if position(3) < app.minWidth || position(4) < app.minHeight
		        app.mouseRelease();
		        app.UIFigure.Position(1:2) = app.lastPos(1:2);
		        if position(3) < app.minWidth
			        app.UIFigure.Position(3) = app.minWidth;
		        end
		        if position(4) < app.minHeight
			        app.UIFigure.Position(4) = app.minHeight;
		        end
	        else
		        app.lastPos = position;
            end
            %}
        end

        % Value changed function: Switch
        function ChangePredatorVision(app, event)
            app.displayPredatorVision = app.Switch.Value;
        end

        % Menu selected function: ApplicationMenu
        function MenuApplicationClicked(app, event)
            uiwait(msgbox({'This application demonstrates implementation of Boids algorythm.'; ...
                'Boids (rectangles) simulate birds movement.'; ...
                'I also added predators, that chase center of a closest flow of boids and which boids avoid.'; ...
                'User can change various parameters, that can manipulate boids and predators movement, as well as their number.'}, "Application Description"));
        end

        % Menu selected function: AuthorMenu
        function MenuAuthorClicked(app, event)
            uiwait(msgbox({'My name is Maciej Müller and I created this application.';
                'My contact email is: maciejmuller@student.agh.edu.pl'}, "Author"));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.AboutMenu = uimenu(app.UIFigure);
            app.AboutMenu.Text = 'About';
            app.AuthorMenu = uimenu(app.AboutMenu);
            app.AuthorMenu.MenuSelectedFcn = createCallbackFcn(app, @MenuAuthorClicked, true);
            app.AuthorMenu.Text = 'Author';
            app.ApplicationMenu = uimenu(app.AboutMenu);
            app.ApplicationMenu.MenuSelectedFcn = createCallbackFcn(app, @MenuApplicationClicked, true);
            app.ApplicationMenu.Text = 'Application';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Plane = uiaxes(app.GridLayout);
            title(app.Plane, 'Boids Simulation')
            app.Plane.XTick = [];
            app.Plane.YTick = [];
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.CohensionSliderLabel = uilabel(app.GridLayout2);
            app.CohensionSliderLabel.Text = 'Cohension';
            app.CohensionSlider = uislider(app.GridLayout2);
            app.CohensionSlider.Limits = [0 10];
            app.CohensionSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.CohensionSlider.MajorTickLabels = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.CohensionSlider.ValueChangedFcn = createCallbackFcn(app, @OnCohensionValueChanged, true);
            app.CohensionSlider.Value = 4;
            app.SeparationSliderLabel = uilabel(app.GridLayout2);
            app.SeparationSliderLabel.Text = 'Separation';
            app.SeparationSlider = uislider(app.GridLayout2);
            app.SeparationSlider.Limits = [0 10];
            app.SeparationSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.SeparationSlider.ValueChangedFcn = createCallbackFcn(app, @OnSeparationValueChanged, true);
            app.SeparationSlider.Value = 2;
            app.AlignmentSliderLabel = uilabel(app.GridLayout2);
            app.AlignmentSliderLabel.Text = 'Alignment';
            app.AlignmentSlider = uislider(app.GridLayout2);
            app.AlignmentSlider.Limits = [0 10];
            app.AlignmentSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.AlignmentSlider.ValueChangedFcn = createCallbackFcn(app, @OnAlignmentValueChanged, true);
            app.AlignmentSlider.Value = 4;
            app.PredatorAvoidanceSliderLabel = uilabel(app.GridLayout2);
            app.PredatorAvoidanceSliderLabel.Text = {'Predator'; 'Avoidance'};
            app.PredatorAvoidanceSlider = uislider(app.GridLayout2);
            app.PredatorAvoidanceSlider.Limits = [0 10];
            app.PredatorAvoidanceSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.PredatorAvoidanceSlider.ValueChangedFcn = createCallbackFcn(app, @OnPredatorAvoidanceValueChanged, true);
            app.PredatorAvoidanceSlider.Value = 2;
            app.SeparationLamp = uilamp(app.GridLayout2);
            app.CohensionLamp = uilamp(app.GridLayout2);
            app.AlignmentLamp = uilamp(app.GridLayout2);
            app.PredatorAvoidanceLamp = uilamp(app.GridLayout2);
            app.PredatorsNumberSliderLabel = uilabel(app.GridLayout2);
            app.PredatorsNumberSliderLabel.Text = {'Predators'; 'Number'};
            app.PredatorsNumberSlider = uislider(app.GridLayout2);
            app.PredatorsNumberSlider.Limits = [0 20];
            app.PredatorsNumberSlider.ValueChangedFcn = createCallbackFcn(app, @OnPredatorsNumberChanged, true);
            app.PredatorsNumberSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.PredatorsNumberSlider.Value = 1;
            app.BoidsNumberSliderLabel = uilabel(app.GridLayout2);
            app.BoidsNumberSliderLabel.Text = {'Boids'; 'Number'};
            app.BoidsNumberSlider = uislider(app.GridLayout2);
            app.BoidsNumberSlider.Limits = [0 40];
            app.BoidsNumberSlider.ValueChangedFcn = createCallbackFcn(app, @OnBoidsNumberChanged, true);
            app.BoidsNumberSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.BoidsNumberSlider.Value = 15;
            app.PredatorsVisionDistanceLabel = uilabel(app.GridLayout2);
            app.PredatorsVisionDistanceLabel.Text = {'Predators'; 'Vision'; 'Distance'};
            app.PredatorsVisionDistanceSlider = uislider(app.GridLayout2);
            app.PredatorsVisionDistanceSlider.Limits = [0 200];
            app.PredatorsVisionDistanceSlider.ValueChangedFcn = createCallbackFcn(app, @OnPredatorsVisionChanged, true);
            app.PredatorsVisionDistanceSlider.Value = 50;
            app.BorderAvoidanceSliderLabel = uilabel(app.GridLayout2);
            app.BorderAvoidanceSliderLabel.Text = {'Border'; 'Avoidance'};
            app.BorderAvoidanceSlider = uislider(app.GridLayout2);
            app.BorderAvoidanceSlider.Limits = [0 10];
            app.BorderAvoidanceSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.BorderAvoidanceSlider.ValueChangedFcn = createCallbackFcn(app, @OnBorderAvoidanceChanged, true);
            app.BorderAvoidanceSlider.Value = 1;
            app.BorderAvoidanceLamp = uilamp(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Switch = uiswitch(app.GridLayout3, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @ChangePredatorVision, true);
            app.Switch.Value = 'On';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project_BoidsSimulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end